classdef imageapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImageJPanel             matlab.ui.container.Panel
        ProcessButton           matlab.ui.control.Button
        FormatDropDownLabel     matlab.ui.control.Label
        FormatDropDown          matlab.ui.control.DropDown
        InputDirectoryButton    matlab.ui.control.Button
        ChannelsSpinnerLabel    matlab.ui.control.Label
        ChannelsSpinner         matlab.ui.control.Spinner
        RawDataCheckBox         matlab.ui.control.CheckBox
        SegmentationPanel       matlab.ui.container.Panel
        NonNegativeMatrixFactorizationCheckBox  matlab.ui.control.CheckBox
        KMeansCheckBox          matlab.ui.control.CheckBox
        SegmentButton           matlab.ui.control.Button
        ClustersSpinner_2Label  matlab.ui.control.Label
        ClustersSpinner_2       matlab.ui.control.Spinner
        ClustersSpinnerLabel    matlab.ui.control.Label
        ClustersSpinner         matlab.ui.control.Spinner
        ColocalzationPanel      matlab.ui.container.Panel
        ColocalizeButton        matlab.ui.control.Button
        segment1DropDownLabel   matlab.ui.control.Label
        segment1DropDown        matlab.ui.control.DropDown
        segment2DropDownLabel   matlab.ui.control.Label
        segment2DropDown        matlab.ui.control.DropDown
        segment3DropDownLabel   matlab.ui.control.Label
        segment3DropDown        matlab.ui.control.DropDown
        ChooseDataButton        matlab.ui.control.Button
        ViewPanel               matlab.ui.container.Panel
        ViewButton              matlab.ui.control.Button
        ViewDropDownLabel       matlab.ui.control.Label
        ViewDropDown            matlab.ui.control.DropDown
        ChoosesourceButton      matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        Label                   matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: InputDirectoryButton
        function InputDirectoryButtonPushed(app, event)
            inputDir = uigetdir();
            if(inputDir ~= 0)
                app.RawDatacheckBox.Enable = 'on';
                app.ProcessButton.Enable = 'on';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            group = 'dialogue'; pref = 'exit'; title = 'Exit Dialogue';
            quest = {'Are you sure you want to exit?'}; pbtns = {'Yes', 'No'};
            exitValue = uigetpref(group, pref, title, quest, pbtns); 
            if(exitValue == 'yes')
                app.delete;
        end

        % Value changed function: RawDataCheckBox
        function RawDataCheckBoxValueChanged(app, event)
            app.FormatDropDownValue.Enable = 'on';            
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            fmtValue = app.FormatDropDown.Value;
            if(strcmp(fmtValue, 'RGB'))
                app.ChannelsSpinner.Enable = 'off';
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            Miji(false)
            MIJ.start
            args = strcat
            
            if(app.RawDataCheckBox.Value)
                if(strcmp(app.FormatDropDown.Enable, 'on') 
                    if(app.ChannelsSpinner.Value == 0)
                        app.ChannelsSpinnerLabel.Fontcolor = 'red';
                        MIJ.exit
                        return;  
                    MIJ.run(imageJcomposite, inputDir, app.ChannelsSpinner.Value)
                else
                    MIJ.run(imageJRGB, inputDir)
                inputDir = inputDir + '\outPut\' + app.FormatDropDown.Value; 
                MIJ.exit
            processVal = processImage(inputDir);
            if(procVal == 0)
                app.Label.Text = 'No ".tif" file types found in Directory';
                app.Label.Visible = 'on'
                return;
            app.Label.Text = inputDir;
            app.Label.Visible = 'on'
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageJPanel = uipanel(app.UIFigure);
            app.ImageJPanel.Title = 'ImageJ';
            app.ProcessButton = uibutton(app.ImageJPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.FormatDropDownLabel = uilabel(app.ImageJPanel);
            app.FormatDropDownLabel.Enable = 'off';
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.ImageJPanel);
            app.FormatDropDown.Items = {'Composite', 'RGB'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Enable = 'off';
            app.FormatDropDown.Value = 'Composite';
            app.InputDirectoryButton = uibutton(app.ImageJPanel, 'push');
            app.InputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @InputDirectoryButtonPushed, true);
            app.InputDirectoryButton.Text = 'Input Directory';
            app.ChannelsSpinnerLabel = uilabel(app.ImageJPanel);
            app.ChannelsSpinnerLabel.Enable = 'off';
            app.ChannelsSpinnerLabel.Text = 'Channels';
            app.ChannelsSpinner = uispinner(app.ImageJPanel);
            app.ChannelsSpinner.Enable = 'off';
            app.RawDataCheckBox = uicheckbox(app.ImageJPanel);
            app.RawDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawDataCheckBoxValueChanged, true);
            app.RawDataCheckBox.Enable = 'off';
            app.RawDataCheckBox.Text = 'Raw Data';
            app.SegmentationPanel = uipanel(app.UIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.NonNegativeMatrixFactorizationCheckBox = uicheckbox(app.SegmentationPanel);
            app.NonNegativeMatrixFactorizationCheckBox.Enable = 'off';
            app.NonNegativeMatrixFactorizationCheckBox.Text = 'Non-Negative Matrix Factorization';
            app.KMeansCheckBox = uicheckbox(app.SegmentationPanel);
            app.KMeansCheckBox.Enable = 'off';
            app.KMeansCheckBox.Text = 'K-Means';
            app.SegmentButton = uibutton(app.SegmentationPanel, 'push');
            app.SegmentButton.Enable = 'off';
            app.SegmentButton.Text = 'Segment';
            app.ClustersSpinner_2Label = uilabel(app.SegmentationPanel);
            app.ClustersSpinner_2Label.Enable = 'off';
            app.ClustersSpinner_2Label.Text = 'Clusters';
            app.ClustersSpinner_2 = uispinner(app.SegmentationPanel);
            app.ClustersSpinner_2.Enable = 'off';
            app.ClustersSpinnerLabel = uilabel(app.SegmentationPanel);
            app.ClustersSpinnerLabel.Enable = 'off';
            app.ClustersSpinnerLabel.Text = 'Clusters';
            app.ClustersSpinner = uispinner(app.SegmentationPanel);
            app.ClustersSpinner.Enable = 'off';
            app.ColocalzationPanel = uipanel(app.UIFigure);
            app.ColocalzationPanel.Title = 'Colocalzation';
            app.ColocalizeButton = uibutton(app.ColocalzationPanel, 'push');
            app.ColocalizeButton.Enable = 'off';
            app.ColocalizeButton.Text = 'Colocalize';
            app.segment1DropDownLabel = uilabel(app.ColocalzationPanel);
            app.segment1DropDownLabel.Enable = 'off';
            app.segment1DropDownLabel.Text = 'segment 1';
            app.segment1DropDown = uidropdown(app.ColocalzationPanel);
            app.segment1DropDown.Items = {};
            app.segment1DropDown.Enable = 'off';
            app.segment1DropDown.Value = {};
            app.segment2DropDownLabel = uilabel(app.ColocalzationPanel);
            app.segment2DropDownLabel.Enable = 'off';
            app.segment2DropDownLabel.Text = 'segment 2';
            app.segment2DropDown = uidropdown(app.ColocalzationPanel);
            app.segment2DropDown.Items = {};
            app.segment2DropDown.Enable = 'off';
            app.segment2DropDown.Value = {};
            app.segment3DropDownLabel = uilabel(app.ColocalzationPanel);
            app.segment3DropDownLabel.Enable = 'off';
            app.segment3DropDownLabel.Text = 'segment 3';
            app.segment3DropDown = uidropdown(app.ColocalzationPanel);
            app.segment3DropDown.Items = {};
            app.segment3DropDown.Enable = 'off';
            app.segment3DropDown.Value = {};
            app.ChooseDataButton = uibutton(app.ColocalzationPanel, 'push');
            app.ChooseDataButton.Enable = 'off';
            app.ChooseDataButton.Text = 'Choose Data';
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.ViewButton = uibutton(app.ViewPanel, 'push');
            app.ViewButton.Enable = 'off';
            app.ViewButton.Text = 'View';
            app.ViewDropDownLabel = uilabel(app.ViewPanel);
            app.ViewDropDownLabel.Enable = 'off';
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.ViewPanel);
            app.ViewDropDown.Items = {'Slice', 'Stack'};
            app.ViewDropDown.Enable = 'off';
            app.ViewDropDown.Value = 'Slice';
            app.ChoosesourceButton = uibutton(app.ViewPanel, 'push');
            app.ChoosesourceButton.Enable = 'off';
            app.ChoosesourceButton.Text = 'Choose source';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imageapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end